classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        QuitButton                    matlab.ui.control.Button
        Switch                        matlab.ui.control.Switch
        Image                         matlab.ui.control.Image
        SteadyLamp                    matlab.ui.control.Lamp
        SteadyLampLabel               matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        BasicTab                      matlab.ui.container.Tab
        PlotwindowsizeEditField       matlab.ui.control.NumericEditField
        PlotwindowsizeEditFieldLabel  matlab.ui.control.Label
        RPMRangeSlider                matlab.ui.control.RangeSlider
        RPMRangeSliderLabel           matlab.ui.control.Label
        CylindersEditField            matlab.ui.control.NumericEditField
        CylindersEditFieldLabel       matlab.ui.control.Label
        StrokesDropDown               matlab.ui.control.DropDown
        StrokesDropDownLabel          matlab.ui.control.Label
        AdvancedTab                   matlab.ui.container.Tab
        STFTSwitch                    matlab.ui.control.Switch
        SampletimeSlider              matlab.ui.control.Slider
        SampletimeSliderLabel         matlab.ui.control.Label
        MaxRPMsEditField              matlab.ui.control.NumericEditField
        MaxRPMsEditFieldLabel         matlab.ui.control.Label
        STDThresholdEditField         matlab.ui.control.NumericEditField
        STDThresholdEditFieldLabel    matlab.ui.control.Label
        CachelengthEditField          matlab.ui.control.NumericEditField
        CachelengthEditFieldLabel     matlab.ui.control.Label
        CombperFTEditField            matlab.ui.control.NumericEditField
        CombperFTEditFieldLabel       matlab.ui.control.Label
        RPMGauge                      matlab.ui.control.Gauge
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        run = false % determins whether the app is running or not
        C = struct("SAMPLE_TIME", 0.5, "CYLINDERS", 4, "CYCLES", 4, ...
            "MIN_RPM", 500, "MAX_RPM", 4500, "CPSTFT", 10, ...
            "CACHE_LEN", 5, "MAX_RPMPS", 1000, "STD_THR", 100, ...
            "DEVICE_TYPE", "pc")
        fs = 44100
        mic
        plot_time_window_size = 20;
        lase_CPSTFT = 10;
    end
    
    methods (Access = private)

        function measure_rpm(app)
            data = zeros(app.C.SAMPLE_TIME*app.fs, 1);
            movavg_rpm = app.C.MIN_RPM;
            movder_rpm = app.C.MAX_RPM;
            rpm_cache = NaN(1, app.C.CACHE_LEN);
            rpm_cache_raw = NaN(1, app.C.CACHE_LEN);
            plot_time = 0;
            plot_rpm = 0;
            time = tic;
            pause(0.1);
            while isvalid(app) && app.run
                tic
                [data, time, dt] = capture_audio(app.mic, data, time, app.C);
                [rpm, ~, rpm_raw] = spectral_analysis_stft_sample(data, app.fs, movavg_rpm, movder_rpm, app.C);
                [movavg_rpm, movder_rpm, steady, rpm_cache, ~, rpm_cache_raw] = moving_avg_analysis(rpm_cache, rpm, rpm_cache_raw, rpm_raw, dt, app.C);
                [plot_rpm, plot_time] = vis_rpm(app, rpm, steady, plot_rpm, plot_time, dt);
                while toc < 0.1
                    pause(0.01);
                end
            end
        end

        function [plot_rpm, plot_time] = vis_rpm(app, rpm, steady, plot_rpm, plot_time, dt)
            plot_time = [plot_time, plot_time(end)+dt];
            plot_rpm = [plot_rpm, rpm];
            if plot_time(end) > app.plot_time_window_size
                range = plot_time < plot_time(end) - app.plot_time_window_size;
                plot_time(range) = [];
                plot_rpm(range) = [];
            end
            app.UIAxes.XLim = [max(0, plot_time(end) - app.plot_time_window_size), max(app.plot_time_window_size, plot_time(end))];
            plot(app.UIAxes, plot_time, plot_rpm);
            app.RPMGauge.Value = rpm;
            if steady
                app.SteadyLamp.Color = [0.17,0.80,0.17];
            else
                app.SteadyLamp.Color = [0.80,0.17,0.17];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mic = audiorecorder(app.fs, 16, 1);
            record(app.mic);
        end

        % Value changed function: RPMRangeSlider
        function RPMRangeSliderValueChanged(app, event)
            rpm_range = app.RPMRangeSlider.Value;
            if app.RPMGauge.Value <= rpm_range(1)
                rpm_range(1) = app.RPMGauge.Value-1;
                app.RPMRangeSlider.Value(1) = rpm_range(1);
            end
            if app.RPMGauge.Value >= rpm_range(2)
                rpm_range(2) = app.RPMGauge.Value+1;
                app.RPMRangeSlider.Value(2) = rpm_range(2);
            end
            app.C.MIN_RPM = rpm_range(1);
            app.C.MAX_RPM = rpm_range(2);
            app.RPMGauge.Limits = rpm_range;
            app.UIAxes.YLim = rpm_range;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.run = app.Switch.Value;
            if app.run
                record(app.mic);
                measure_rpm(app);
            end
        end

        % Value changed function: SampletimeSlider
        function SampletimeSliderValueChanged(app, event)
            app.C.SAMPLE_TIME = app.SampletimeSlider.Value;
        end

        % Value changed function: CylindersEditField
        function CylindersEditFieldValueChanged(app, event)
            app.C.CYLINDERS = app.CylindersEditField.Value;
        end

        % Value changed function: StrokesDropDown
        function StrokesDropDownValueChanged(app, event)
            app.C.CYCLES = app.StrokesDropDown.Value;
        end

        % Value changed function: MaxRPMsEditField
        function MaxRPMsEditFieldValueChanged(app, event)
            app.C.MAX_RPMPS = app.MaxRPMsEditField.Value;
        end

        % Value changed function: STDThresholdEditField
        function STDThresholdEditFieldValueChanged(app, event)
            app.C.STD_THR = app.STDThresholdEditField.Value;
        end

        % Value changed function: CachelengthEditField
        function CachelengthEditFieldValueChanged(app, event)
            app.C.CACHE_LEN = app.CachelengthEditField.Value;
        end

        % Value changed function: CombperFTEditField
        function CombperFTEditFieldValueChanged(app, event)
            app.C.CPSTFT = app.CombperFTEditField.Value;
            app.lase_CPSTFT = app.C.CPSTFT;
        end

        % Value changed function: PlotwindowsizeEditField
        function PlotwindowsizeEditFieldValueChanged(app, event)
            app.plot_time_window_size = app.PlotwindowsizeEditField.Value;
            app.UIAxes.XLim = [0, app.plot_time_window_size];
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: STFTSwitch
        function STFTSwitchValueChanged(app, event)
            stft_switch = app.STFTSwitch.Value;
            if stft_switch
                app.C.CPSTFT = app.lase_CPSTFT;
                app.CombperFTEditField.Value = app.C.CPSTFT;
                app.CombperFTEditField.Enable = "on";
            else
                app.C.CPSTFT = Inf;
                app.CombperFTEditField.Value = Inf;
                app.CombperFTEditField.Enable = "off";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'RPM')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 4500];
            app.RPMGauge = uigauge(app.UIFigure, 'circular');
            app.RPMGauge.Limits = [500 4500];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.StrokesDropDownLabel = uilabel(app.BasicTab);
            app.StrokesDropDownLabel.Text = 'Strokes';
            app.StrokesDropDown = uidropdown(app.BasicTab);
            app.StrokesDropDown.Items = {'4', '2'};
            app.StrokesDropDown.ItemsData = [4 2];
            app.StrokesDropDown.ValueChangedFcn = createCallbackFcn(app, @StrokesDropDownValueChanged, true);
            app.StrokesDropDown.Tooltip = {'Number of engine strokes. Only 4-stroke and 2-stroke internal combustion engines supported.'};
            app.StrokesDropDown.Value = 4;
            app.CylindersEditFieldLabel = uilabel(app.BasicTab);
            app.CylindersEditFieldLabel.Text = 'Cylinders';
            app.CylindersEditField = uieditfield(app.BasicTab, 'numeric');
            app.CylindersEditField.Limits = [1 Inf];
            app.CylindersEditField.RoundFractionalValues = 'on';
            app.CylindersEditField.ValueChangedFcn = createCallbackFcn(app, @CylindersEditFieldValueChanged, true);
            app.CylindersEditField.Tooltip = {'Number of cylinders of engine.'};
            app.CylindersEditField.Value = 4;
            app.RPMRangeSliderLabel = uilabel(app.BasicTab);
            app.RPMRangeSliderLabel.Text = 'RPM Range';
            app.RPMRangeSlider = uislider(app.BasicTab, 'range');
            app.RPMRangeSlider.Limits = [0 8000];
            app.RPMRangeSlider.MajorTicks = [0 2000 4000 6000 8000];
            app.RPMRangeSlider.ValueChangedFcn = createCallbackFcn(app, @RPMRangeSliderValueChanged, true);
            app.RPMRangeSlider.Tooltip = {'Expected range of engine RPM. The smaller the range, the more accurate the results will be.'};
            app.RPMRangeSlider.Value = [500 4500];
            app.PlotwindowsizeEditFieldLabel = uilabel(app.BasicTab);
            app.PlotwindowsizeEditFieldLabel.Text = 'Plot window size';
            app.PlotwindowsizeEditField = uieditfield(app.BasicTab, 'numeric');
            app.PlotwindowsizeEditField.Limits = [1 Inf];
            app.PlotwindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @PlotwindowsizeEditFieldValueChanged, true);
            app.PlotwindowsizeEditField.Tooltip = {'Seconds of logged RPM visualized in the plot.'};
            app.PlotwindowsizeEditField.Value = 20;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.CombperFTEditFieldLabel = uilabel(app.AdvancedTab);
            app.CombperFTEditFieldLabel.Text = 'Comb. per FT';
            app.CombperFTEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.CombperFTEditField.Limits = [1 Inf];
            app.CombperFTEditField.RoundFractionalValues = 'on';
            app.CombperFTEditField.ValueChangedFcn = createCallbackFcn(app, @CombperFTEditFieldValueChanged, true);
            app.CombperFTEditField.Tooltip = {'Engine combustions per STFT window.  Smaller value may lead to outlier RPM, higher value to noisy RPM.'};
            app.CombperFTEditField.Value = 10;
            app.CachelengthEditFieldLabel = uilabel(app.AdvancedTab);
            app.CachelengthEditFieldLabel.Text = 'Cache length';
            app.CachelengthEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.CachelengthEditField.Limits = [1 Inf];
            app.CachelengthEditField.RoundFractionalValues = 'on';
            app.CachelengthEditField.ValueChangedFcn = createCallbackFcn(app, @CachelengthEditFieldValueChanged, true);
            app.CachelengthEditField.Tooltip = {'Length of cached RPM for moving average calcualtion. Smaller value leads to mor noisy data, higher value to slower responses.'};
            app.CachelengthEditField.Value = 5;
            app.STDThresholdEditFieldLabel = uilabel(app.AdvancedTab);
            app.STDThresholdEditFieldLabel.Tooltip = {''};
            app.STDThresholdEditFieldLabel.Text = 'STD Threshold';
            app.STDThresholdEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.STDThresholdEditField.Limits = [0 Inf];
            app.STDThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @STDThresholdEditFieldValueChanged, true);
            app.STDThresholdEditField.Tooltip = {'Standart deviation threshold, so that the sample is considered to be fluctuating w.r.t. the cache.'};
            app.STDThresholdEditField.Value = 100;
            app.MaxRPMsEditFieldLabel = uilabel(app.AdvancedTab);
            app.MaxRPMsEditFieldLabel.Text = 'Max RPM/s';
            app.MaxRPMsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MaxRPMsEditField.Limits = [0 Inf];
            app.MaxRPMsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRPMsEditFieldValueChanged, true);
            app.MaxRPMsEditField.Tooltip = {'Maximum RPM velocity (RPM per second). Determines what is the maximum change of RPM per second. Is used for data smoothing.'};
            app.MaxRPMsEditField.Value = 1000;
            app.SampletimeSliderLabel = uilabel(app.AdvancedTab);
            app.SampletimeSliderLabel.Text = 'Sample time';
            app.SampletimeSlider = uislider(app.AdvancedTab);
            app.SampletimeSlider.Limits = [0.1 0.9];
            app.SampletimeSlider.MajorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.SampletimeSlider.ValueChangedFcn = createCallbackFcn(app, @SampletimeSliderValueChanged, true);
            app.SampletimeSlider.Tooltip = {'Lenght of time sample used for analysis. Smaller sample time leads to more noise, high sample time has worse time resolution.'};
            app.SampletimeSlider.Value = 0.5;
            app.STFTSwitch = uiswitch(app.AdvancedTab, 'slider');
            app.STFTSwitch.Items = {'STFT', 'FFT'};
            app.STFTSwitch.ItemsData = [true false];
            app.STFTSwitch.ValueChangedFcn = createCallbackFcn(app, @STFTSwitchValueChanged, true);
            app.STFTSwitch.Tooltip = {'STFT means every sample will be devided into multiple smaller windows and averaged. FFT will compute with the sample as whole.'};
            app.STFTSwitch.Value = true;
            app.SteadyLampLabel = uilabel(app.UIFigure);
            app.SteadyLampLabel.Text = 'Steady';
            app.SteadyLamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.URL = 'www.fel.cvut.cz';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'data', 'symbol_cvut_plna_doplnkova_verze.jpg');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Start', 'Stop'};
            app.Switch.ItemsData = [true false];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Start and stop the programm.'};
            app.Switch.Value = false;
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end